classdef Malthusian_equation_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StartpopulationSliderLabel      matlab.ui.control.Label
        StartpopulationSlider           matlab.ui.control.Slider
        BirthrateSliderLabel            matlab.ui.control.Label
        BirthrateSlider                 matlab.ui.control.Slider
        DeathrateSliderLabel            matlab.ui.control.Label
        DeathrateSlider                 matlab.ui.control.Slider
        NumberofitterationsSliderLabel  matlab.ui.control.Label
        NumberofitterationsSlider       matlab.ui.control.Slider
        UIAxes                          matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: StartpopulationSlider
        function StartpopulationSliderValueChanging(app, event)
            changingValue = event.Value;
                           
        a=[0:app.NumberofitterationsSlider.Value];
        b(1)=app.StartpopulationSlider.Value;
        for n=2:(app.NumberofitterationsSlider.Value +1)
          b(n)=(1+app.BirthrateSlider.Value-app.DeathrateSlider.Value)*b(n-1);
          if b(n)<=0
              b(n)=0;
          end
        end


        plot(app.UIAxes,a,b);
        Xlim([0,inf]);
        Ylim([0,inf]);
        end

        % Value changing function: BirthrateSlider
        function BirthrateSliderValueChanging(app, event)
            changingValue = event.Value;
                           
        a=[0:app.NumberofitterationsSlider.Value];
        b(1)=app.StartpopulationSlider.Value;
        for n=2:(app.NumberofitterationsSlider.Value +1)
          b(n)=(1+app.BirthrateSlider.Value-app.DeathrateSlider.Value)*b(n-1);
          if b(n)<=0
              b(n)=0;
          end
        end


        plot(app.UIAxes,a,b);
        Xlim([0,inf]);
        Ylim([0,inf]);
        end

        % Value changing function: DeathrateSlider
        function DeathrateSliderValueChanging(app, event)
            changingValue = event.Value;
                           
        a=[0:app.NumberofitterationsSlider.Value];
        b(1)=app.StartpopulationSlider.Value;
        for n=2:(app.NumberofitterationsSlider.Value +1)
          b(n)=(1+app.BirthrateSlider.Value-app.DeathrateSlider.Value)*b(n-1);
          if b(n)<=0
              b(n)=0;
          end
        end


        plot(app.UIAxes,a,b);
        Xlim([0,inf]);
        Ylim([0,inf]);
        end

        % Value changing function: NumberofitterationsSlider
        function NumberofitterationsSliderValueChanging(app, event)
            changingValue = event.Value;
                           
        a=[0:app.NumberofitterationsSlider.Value];
        b(1)=app.StartpopulationSlider.Value;
        for n=2:(app.NumberofitterationsSlider.Value +1)
          b(n)=(1+app.BirthrateSlider.Value-app.DeathrateSlider.Value)*b(n-1);
          if b(n)<=0
              b(n)=0;
          end
        end


        plot(app.UIAxes,a,b);
        Xlim([0,inf]);
        Ylim([0,inf]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.StartpopulationSliderLabel = uilabel(app.UIFigure);
            app.StartpopulationSliderLabel.Text = 'Start population';
            app.StartpopulationSlider = uislider(app.UIFigure);
            app.StartpopulationSlider.ValueChangingFcn = createCallbackFcn(app, @StartpopulationSliderValueChanging, true);
            app.BirthrateSliderLabel = uilabel(app.UIFigure);
            app.BirthrateSliderLabel.Text = 'Birth rate';
            app.BirthrateSlider = uislider(app.UIFigure);
            app.BirthrateSlider.Limits = [0 1];
            app.BirthrateSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.BirthrateSlider.ValueChangingFcn = createCallbackFcn(app, @BirthrateSliderValueChanging, true);
            app.DeathrateSliderLabel = uilabel(app.UIFigure);
            app.DeathrateSliderLabel.Text = 'Death rate';
            app.DeathrateSlider = uislider(app.UIFigure);
            app.DeathrateSlider.Limits = [0 1];
            app.DeathrateSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.DeathrateSlider.ValueChangingFcn = createCallbackFcn(app, @DeathrateSliderValueChanging, true);
            app.NumberofitterationsSliderLabel = uilabel(app.UIFigure);
            app.NumberofitterationsSliderLabel.Text = 'Number of itterations';
            app.NumberofitterationsSlider = uislider(app.UIFigure);
            app.NumberofitterationsSlider.ValueChangingFcn = createCallbackFcn(app, @NumberofitterationsSliderValueChanging, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Malthusian equation')
            xlabel(app.UIAxes, 'Number of itterations')
            ylabel(app.UIAxes, 'Population')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Malthusian_equation_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end