classdef NIcholson_Bailey_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure  matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        NicholsonBaileyHostParasiteModelLabel  matlab.ui.control.Label
        HostVariablesLabel      matlab.ui.control.Label
        ParasiteVariablesLabel  matlab.ui.control.Label
        AppcreatedbyDavidRhysGemmellLabel  matlab.ui.control.Label
        N_0SliderLabel          matlab.ui.control.Label
        N_0Slider               matlab.ui.control.Slider
        P_0SliderLabel          matlab.ui.control.Label
        P_0Slider               matlab.ui.control.Slider
        aSliderLabel            matlab.ui.control.Label
        aSlider                 matlab.ui.control.Slider
        cSliderLabel            matlab.ui.control.Label
        cSlider                 matlab.ui.control.Slider
        LambdaSliderLabel       matlab.ui.control.Label
        LambdaSlider            matlab.ui.control.Slider
        TimeSliderLabel         matlab.ui.control.Label
        tSlider                 matlab.ui.control.Slider
        N_0Label                matlab.ui.control.Label
        N_0EditField            matlab.ui.control.NumericEditField
        LambdaLabel             matlab.ui.control.Label
        LambdaEditField         matlab.ui.control.NumericEditField
        P_0EditFieldLabel       matlab.ui.control.Label
        P_0EditField            matlab.ui.control.NumericEditField
        cEditFieldLabel         matlab.ui.control.Label
        cEditField              matlab.ui.control.NumericEditField
        aEditFieldLabel         matlab.ui.control.Label
        aEditField              matlab.ui.control.NumericEditField
        TimeEditFieldLabel      matlab.ui.control.Label
        TimeEditField           matlab.ui.control.NumericEditField
        Image                   matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: N_0Slider
        function N_0SliderValueChanged(app, event)
            value = app.N_0Slider.Value;
            app.N_0EditField.Value=app.N_0Slider.Value;
          %Nicholson Bailey%

%================================================

%-------------------------------------------------
%parameters
%-------------------------------------------------
lambda=app.LambdaSlider.Value; %Host growth rate%
a=app.aSlider.Value; %searching efficiency of parasite%
c=app.cSlider.Value; %viable eggs that can be on a single host%
N0=app.N_0Slider.Value; %Host density at t=0%
P0=app.P_0Slider.Value; %Parasite density at t=0%;
%function
%-------------------------------------------------
f=@(N,P) N.*lambda*exp(-a*P); %host%
g=@(N,P) N.*c*(1-exp(-a*P));  %Parasite%

%-------------------------------------------------
%initial conditions%
A(1)=N0;%host is column one
B(1)=P0; %parasite is column two
%time/generations/whatever%
t_end=app.tSlider.Value; 
T=[0:t_end]';

%create vectors for f&g%
for i=2:t_end+1
    A(i)=f(A(i-1),B(i-1)); 
    B(i)= g(A(i-1),B(i-1));
end
app.UIAxes2.cla
plot(app.UIAxes2,T,A)

plot(app.UIAxes2,T,B)
legend(app.UIAxes2, 'Host','Parasite')


app.UIAxes.cla
plot(app.UIAxes,log10(A),log10(B))
 
        end

        % Value changed function: P_0Slider
        function P_0SliderValueChanged(app, event)
            value = app.P_0Slider.Value;
            app.P_0EditField.Value=app.P_0Slider.Value;
                        
          %Nicholson Bailey%

%================================================

%-------------------------------------------------
%parameters
%-------------------------------------------------
lambda=app.LambdaSlider.Value; %Host growth rate%
a=app.aSlider.Value; %searching efficiency of parasite%
c=app.cSlider.Value; %viable eggs that can be on a single host%
N0=app.N_0Slider.Value; %Host density at t=0%
P0=app.P_0Slider.Value; %Parasite density at t=0%;
%function
%-------------------------------------------------
f=@(N,P) N.*lambda*exp(-a*P); %host%
g=@(N,P) N.*c*(1-exp(-a*P));  %Parasite%

%-------------------------------------------------
%initial conditions%
A(1)=N0;%host is column one
B(1)=P0; %parasite is column two
%time/generations/whatever%
t_end=app.tSlider.Value; 
T=[0:t_end]';

%create vectors for f&g%
for i=2:t_end+1
    A(i)=f(A(i-1),B(i-1)); 
    B(i)= g(A(i-1),B(i-1));
end
app.UIAxes2.cla
plot(app.UIAxes2,T,A)

plot(app.UIAxes2,T,B)
legend(app.UIAxes2, 'Host','Parasite')
app.UIAxes.cla
plot(app.UIAxes,log10(A),log10(B))
        end

        % Value changed function: aSlider
        function aSliderValueChanged(app, event)
            value = app.aSlider.Value;
            app.aEditField.Value=app.aSlider.Value;
                       
          %Nicholson Bailey%

%================================================

%-------------------------------------------------
%parameters
%-------------------------------------------------
lambda=app.LambdaSlider.Value; %Host growth rate%
a=app.aSlider.Value; %searching efficiency of parasite%
c=app.cSlider.Value; %viable eggs that can be on a single host%
N0=app.N_0Slider.Value; %Host density at t=0%
P0=app.P_0Slider.Value; %Parasite density at t=0%;
%function
%-------------------------------------------------
f=@(N,P) N.*lambda*exp(-a*P); %host%
g=@(N,P) N.*c*(1-exp(-a*P));  %Parasite%

%-------------------------------------------------
%initial conditions%
A(1)=N0;%host is column one
B(1)=P0; %parasite is column two
%time/generations/whatever%
t_end=app.tSlider.Value; 
T=[0:t_end]';

%create vectors for f&g%
for i=2:t_end+1
    A(i)=f(A(i-1),B(i-1)); 
    B(i)= g(A(i-1),B(i-1));
end
app.UIAxes2.cla
plot(app.UIAxes2,T,A)

plot(app.UIAxes2,T,B)
legend(app.UIAxes2, 'Host','Parasite')
app.UIAxes.cla
plot(app.UIAxes,log10(A),log10(B))
        end

        % Value changed function: cSlider
        function cSliderValueChanged(app, event)
            value = app.cSlider.Value;
            app.cEditField.Value=app.cSlider.Value;
                       
          %Nicholson Bailey%

%================================================

%-------------------------------------------------
%parameters
%-------------------------------------------------
lambda=app.LambdaSlider.Value; %Host growth rate%
a=app.aSlider.Value; %searching efficiency of parasite%
c=app.cSlider.Value; %viable eggs that can be on a single host%
N0=app.N_0Slider.Value; %Host density at t=0%
P0=app.P_0Slider.Value; %Parasite density at t=0%;
%function
%-------------------------------------------------
f=@(N,P) N.*lambda*exp(-a*P); %host%
g=@(N,P) N.*c*(1-exp(-a*P));  %Parasite%

%-------------------------------------------------
%initial conditions%
A(1)=N0;%host is column one
B(1)=P0; %parasite is column two
%time/generations/whatever%
t_end=app.tSlider.Value; 
T=[0:t_end]';

%create vectors for f&g%
for i=2:t_end+1
    A(i)=f(A(i-1),B(i-1)); 
    B(i)= g(A(i-1),B(i-1));
end
app.UIAxes2.cla
plot(app.UIAxes2,T,A)

plot(app.UIAxes2,T,B)
legend(app.UIAxes2, 'Host','Parasite')
app.UIAxes.cla
plot(app.UIAxes,log10(A),log10(B))
        end

        % Value changed function: LambdaSlider
        function LambdaSliderValueChanged(app, event)
            value = app.LambdaSlider.Value;
            app.LambdaEditField.Value=app.LambdaSlider.Value;
                        
          %Nicholson Bailey%

%================================================

%-------------------------------------------------
%parameters
%-------------------------------------------------
lambda=app.LambdaSlider.Value; %Host growth rate%
a=app.aSlider.Value; %searching efficiency of parasite%
c=app.cSlider.Value; %viable eggs that can be on a single host%
N0=app.N_0Slider.Value; %Host density at t=0%
P0=app.P_0Slider.Value; %Parasite density at t=0%;
%function
%-------------------------------------------------
f=@(N,P) N.*lambda*exp(-a*P); %host%
g=@(N,P) N.*c*(1-exp(-a*P));  %Parasite%

%-------------------------------------------------
%initial conditions%
A(1)=N0;%host is column one
B(1)=P0; %parasite is column two
%time/generations/whatever%
t_end=app.tSlider.Value; 
T=[0:t_end]';

%create vectors for f&g%
for i=2:t_end+1
    A(i)=f(A(i-1),B(i-1)); 
    B(i)= g(A(i-1),B(i-1));
end
app.UIAxes2.cla
plot(app.UIAxes2,T,A)

plot(app.UIAxes2,T,B)
legend(app.UIAxes2, 'Host','Parasite')
app.UIAxes.cla
plot(app.UIAxes,log10(A),log10(B))
        end

        % Value changed function: tSlider
        function tSliderValueChanged(app, event)
            value = app.tSlider.Value;
            app.TimeEditField.Value=app.tSlider.Value
                      
          %Nicholson Bailey%

%================================================

%-------------------------------------------------
%parameters
%-------------------------------------------------
lambda=app.LambdaSlider.Value; %Host growth rate%
a=app.aSlider.Value; %searching efficiency of parasite%
c=app.cSlider.Value; %viable eggs that can be on a single host%
N0=app.N_0Slider.Value; %Host density at t=0%
P0=app.P_0Slider.Value; %Parasite density at t=0%;
%function
%-------------------------------------------------
f=@(N,P) N.*lambda*exp(-a*P); %host%
g=@(N,P) N.*c*(1-exp(-a*P));  %Parasite%

%-------------------------------------------------
%initial conditions%
A(1)=N0;%host is column one
B(1)=P0; %parasite is column two
%time/generations/whatever%
t_end=app.tSlider.Value; 
T=[0:t_end]';

%create vectors for f&g%
for i=2:t_end+1
    A(i)=f(A(i-1),B(i-1)); 
    B(i)= g(A(i-1),B(i-1));
end
app.UIAxes2.cla
plot(app.UIAxes2,T,A)

plot(app.UIAxes2,T,B)
legend(app.UIAxes2, 'Host','Parasite')
app.UIAxes.cla
plot(app.UIAxes,log10(A),log10(B))
        end

        % Value changed function: N_0EditField
        function N_0EditFieldValueChanged(app, event)
            value = app.N_0EditField.Value;
            app.N_0Slider.Value=app.N_0EditField.Value;
                      %Nicholson Bailey%

%================================================

%-------------------------------------------------
%parameters
%-------------------------------------------------
lambda=app.LambdaSlider.Value; %Host growth rate%
a=app.aSlider.Value; %searching efficiency of parasite%
c=app.cSlider.Value; %viable eggs that can be on a single host%
N0=app.N_0Slider.Value; %Host density at t=0%
P0=app.P_0Slider.Value; %Parasite density at t=0%;
%function
%-------------------------------------------------
f=@(N,P) N.*lambda*exp(-a*P); %host%
g=@(N,P) N.*c*(1-exp(-a*P));  %Parasite%

%-------------------------------------------------
%initial conditions%
A(1)=N0;%host is column one
B(1)=P0; %parasite is column two
%time/generations/whatever%
t_end=app.tSlider.Value; 
T=[0:t_end]';

%create vectors for f&g%
for i=2:t_end+1
    A(i)=f(A(i-1),B(i-1)); 
    B(i)= g(A(i-1),B(i-1));
end
app.UIAxes2.cla
plot(app.UIAxes2,T,A)

plot(app.UIAxes2,T,B)
legend(app.UIAxes2, 'Host','Parasite')
app.UIAxes.cla
plot(app.UIAxes,log10(A),log10(B))
        end

        % Value changed function: LambdaEditField
        function LambdaEditFieldValueChanged(app, event)
            value = app.LambdaEditField.Value;
            app.LambdaSlider.Value=app.LambdaEditField.Value;
                      %Nicholson Bailey%

%================================================

%-------------------------------------------------
%parameters
%-------------------------------------------------
lambda=app.LambdaSlider.Value; %Host growth rate%
a=app.aSlider.Value; %searching efficiency of parasite%
c=app.cSlider.Value; %viable eggs that can be on a single host%
N0=app.N_0Slider.Value; %Host density at t=0%
P0=app.P_0Slider.Value; %Parasite density at t=0%;
%function
%-------------------------------------------------
f=@(N,P) N.*lambda*exp(-a*P); %host%
g=@(N,P) N.*c*(1-exp(-a*P));  %Parasite%

%-------------------------------------------------
%initial conditions%
A(1)=N0;%host is column one
B(1)=P0; %parasite is column two
%time/generations/whatever%
t_end=app.tSlider.Value; 
T=[0:t_end]';

%create vectors for f&g%
for i=2:t_end+1
    A(i)=f(A(i-1),B(i-1)); 
    B(i)= g(A(i-1),B(i-1));
end
app.UIAxes2.cla
plot(app.UIAxes2,T,A)

plot(app.UIAxes2,T,B)
legend(app.UIAxes2, 'Host','Parasite')
app.UIAxes.cla
plot(app.UIAxes,log10(A),log10(B))
        end

        % Value changed function: P_0EditField
        function P_0EditFieldValueChanged(app, event)
            value = app.P_0EditField.Value;
            app.P_0Slider.Value=app.P_0EditField.Value;
                      %Nicholson Bailey%

%================================================

%-------------------------------------------------
%parameters
%-------------------------------------------------
lambda=app.LambdaSlider.Value; %Host growth rate%
a=app.aSlider.Value; %searching efficiency of parasite%
c=app.cSlider.Value; %viable eggs that can be on a single host%
N0=app.N_0Slider.Value; %Host density at t=0%
P0=app.P_0Slider.Value; %Parasite density at t=0%;
%function
%-------------------------------------------------
f=@(N,P) N.*lambda*exp(-a*P); %host%
g=@(N,P) N.*c*(1-exp(-a*P));  %Parasite%

%-------------------------------------------------
%initial conditions%
A(1)=N0;%host is column one
B(1)=P0; %parasite is column two
%time/generations/whatever%
t_end=app.tSlider.Value; 
T=[0:t_end]';

%create vectors for f&g%
for i=2:t_end+1
    A(i)=f(A(i-1),B(i-1)); 
    B(i)= g(A(i-1),B(i-1));
end
app.UIAxes2.cla
plot(app.UIAxes2,T,A)

plot(app.UIAxes2,T,B)
legend(app.UIAxes2, 'Host','Parasite')
app.UIAxes.cla
plot(app.UIAxes,log10(A),log10(B))
        end

        % Value changed function: cEditField
        function cEditFieldValueChanged(app, event)
            value = app.cEditField.Value;
            app.cSlider.Value=app.cEditField.Value;
                      %Nicholson Bailey%

%================================================

%-------------------------------------------------
%parameters
%-------------------------------------------------
lambda=app.LambdaSlider.Value; %Host growth rate%
a=app.aSlider.Value; %searching efficiency of parasite%
c=app.cSlider.Value; %viable eggs that can be on a single host%
N0=app.N_0Slider.Value; %Host density at t=0%
P0=app.P_0Slider.Value; %Parasite density at t=0%;
%function
%-------------------------------------------------
f=@(N,P) N.*lambda*exp(-a*P); %host%
g=@(N,P) N.*c*(1-exp(-a*P));  %Parasite%

%-------------------------------------------------
%initial conditions%
A(1)=N0;%host is column one
B(1)=P0; %parasite is column two
%time/generations/whatever%
t_end=app.tSlider.Value; 
T=[0:t_end]';

%create vectors for f&g%
for i=2:t_end+1
    A(i)=f(A(i-1),B(i-1)); 
    B(i)= g(A(i-1),B(i-1));
end
app.UIAxes2.cla
plot(app.UIAxes2,T,A)

plot(app.UIAxes2,T,B)
legend(app.UIAxes2, 'Host','Parasite')
app.UIAxes.cla
plot(app.UIAxes,log10(A),log10(B))
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            value = app.aEditField.Value;
            app.aSlider.Value=app.aEditField.Value;
                      %Nicholson Bailey%

%================================================

%-------------------------------------------------
%parameters
%-------------------------------------------------
lambda=app.LambdaSlider.Value; %Host growth rate%
a=app.aSlider.Value; %searching efficiency of parasite%
c=app.cSlider.Value; %viable eggs that can be on a single host%
N0=app.N_0Slider.Value; %Host density at t=0%
P0=app.P_0Slider.Value; %Parasite density at t=0%;
%function
%-------------------------------------------------
f=@(N,P) N.*lambda*exp(-a*P); %host%
g=@(N,P) N.*c*(1-exp(-a*P));  %Parasite%

%-------------------------------------------------
%initial conditions%
A(1)=N0;%host is column one
B(1)=P0; %parasite is column two
%time/generations/whatever%
t_end=app.tSlider.Value; 
T=[0:t_end]';

%create vectors for f&g%
for i=2:t_end+1
    A(i)=f(A(i-1),B(i-1)); 
    B(i)= g(A(i-1),B(i-1));
end
app.UIAxes2.cla
plot(app.UIAxes2,T,A)

plot(app.UIAxes2,T,B)
legend(app.UIAxes2, 'Host','Parasite')
app.UIAxes.cla
plot(app.UIAxes,log10(A),log10(B))
        end

        % Value changed function: TimeEditField
        function TimeEditFieldValueChanged(app, event)
            value = app.TimeEditField.Value;
            app.tSlider.Value=app.TimeEditField.Value;
                      %Nicholson Bailey%

%================================================

%-------------------------------------------------
%parameters
%-------------------------------------------------
lambda=app.LambdaSlider.Value; %Host growth rate%
a=app.aSlider.Value; %searching efficiency of parasite%
c=app.cSlider.Value; %viable eggs that can be on a single host%
N0=app.N_0Slider.Value; %Host density at t=0%
P0=app.P_0Slider.Value; %Parasite density at t=0%;
%function
%-------------------------------------------------
f=@(N,P) N.*lambda*exp(-a*P); %host%
g=@(N,P) N.*c*(1-exp(-a*P));  %Parasite%

%-------------------------------------------------
%initial conditions%
A(1)=N0;%host is column one
B(1)=P0; %parasite is column two
%time/generations/whatever%
t_end=app.tSlider.Value; 
T=[0:t_end]';

%create vectors for f&g%
for i=2:t_end+1
    A(i)=f(A(i-1),B(i-1)); 
    B(i)= g(A(i-1),B(i-1));
end
app.UIAxes2.cla
plot(app.UIAxes2,T,A)

plot(app.UIAxes2,T,B)
legend(app.UIAxes2, 'Host','Parasite')
app.UIAxes.cla
plot(app.UIAxes,log10(A),log10(B))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure = uifigure('Visible', 'off');
            app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure.Name = 'Nicholson-Bailey: Host-Parasite Model. App by David Rhys Gemmell.';
            app.UIAxes = uiaxes(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure);
            title(app.UIAxes, 'Phase Plane')
            xlabel(app.UIAxes, 'Host Population (Log10)')
            ylabel(app.UIAxes, 'Parasite Population (Log10)')
            app.UIAxes2 = uiaxes(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure);
            title(app.UIAxes2, 'Population size of Hosts and Parasites')
            xlabel(app.UIAxes2, 'time')
            ylabel(app.UIAxes2, 'Population')
            app.NicholsonBaileyHostParasiteModelLabel = uilabel(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure);
            app.NicholsonBaileyHostParasiteModelLabel.Text = 'Nicholson-Bailey: Host-Parasite Model';
            app.HostVariablesLabel = uilabel(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure);
            app.HostVariablesLabel.Text = 'Host Variables';
            app.ParasiteVariablesLabel = uilabel(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure);
            app.ParasiteVariablesLabel.Text = 'Parasite Variables';
            app.AppcreatedbyDavidRhysGemmellLabel = uilabel(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure);
            app.AppcreatedbyDavidRhysGemmellLabel.Text = 'App created by David Rhys Gemmell';
            app.N_0SliderLabel = uilabel(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure);
            app.N_0SliderLabel.Text = 'N_0';
            app.N_0Slider = uislider(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure);
            app.N_0Slider.ValueChangedFcn = createCallbackFcn(app, @N_0SliderValueChanged, true);
            app.P_0SliderLabel = uilabel(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure);
            app.P_0SliderLabel.Text = 'P_0';
            app.P_0Slider = uislider(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure);
            app.P_0Slider.ValueChangedFcn = createCallbackFcn(app, @P_0SliderValueChanged, true);
            app.aSliderLabel = uilabel(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure);
            app.aSliderLabel.Text = 'a';
            app.aSlider = uislider(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure);
            app.aSlider.Limits = [0 1];
            app.aSlider.ValueChangedFcn = createCallbackFcn(app, @aSliderValueChanged, true);
            app.cSliderLabel = uilabel(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure);
            app.cSliderLabel.Text = 'c';
            app.cSlider = uislider(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure);
            app.cSlider.Limits = [0 10];
            app.cSlider.ValueChangedFcn = createCallbackFcn(app, @cSliderValueChanged, true);
            app.LambdaSliderLabel = uilabel(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure);
            app.LambdaSliderLabel.Text = 'Lambda';
            app.LambdaSlider = uislider(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure);
            app.LambdaSlider.ValueChangedFcn = createCallbackFcn(app, @LambdaSliderValueChanged, true);
            app.TimeSliderLabel = uilabel(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.tSlider = uislider(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure);
            app.tSlider.ValueChangedFcn = createCallbackFcn(app, @tSliderValueChanged, true);
            app.N_0Label = uilabel(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure);
            app.N_0Label.Text = 'N_0';
            app.N_0EditField = uieditfield(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure, 'numeric');
            app.N_0EditField.ValueChangedFcn = createCallbackFcn(app, @N_0EditFieldValueChanged, true);
            app.LambdaLabel = uilabel(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure);
            app.LambdaLabel.Text = 'Lambda';
            app.LambdaEditField = uieditfield(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure, 'numeric');
            app.LambdaEditField.ValueChangedFcn = createCallbackFcn(app, @LambdaEditFieldValueChanged, true);
            app.P_0EditFieldLabel = uilabel(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure);
            app.P_0EditFieldLabel.Text = 'P_0';
            app.P_0EditField = uieditfield(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure, 'numeric');
            app.P_0EditField.ValueChangedFcn = createCallbackFcn(app, @P_0EditFieldValueChanged, true);
            app.cEditFieldLabel = uilabel(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure, 'numeric');
            app.cEditField.ValueChangedFcn = createCallbackFcn(app, @cEditFieldValueChanged, true);
            app.aEditFieldLabel = uilabel(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.TimeEditFieldLabel = uilabel(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure, 'numeric');
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEditFieldValueChanged, true);
            app.Image = uiimage(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure);
            app.Image.ImageSource = 'Nicholson Bailey equations screenshot egg blue.png';
            % Show the figure after all components are created
            app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NIcholson_Bailey_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NicholsonBaileyHostParasiteModelAppbyDavidRhysGemmellUIFigure)
        end
    end
end