classdef lab3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        UIAxes        matlab.ui.control.UIAxes
        rSliderLabel  matlab.ui.control.Label
        rSlider       matlab.ui.control.Slider
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: rSlider
        function rSliderValueChanged(app, event)
            value = app.rSlider.Value;
            r=app.rSlider.Value;
            f=@(X) r*X.*(1-X);
            Xinit_vec=[0.3];
            nmax=30;
            [ST,I] = dbstack;
calling_filename=ST(2).file;

num_ICs=length(Xinit_vec);

%create vector of n values
nvec=(1:nmax)';

for j=1:num_ICs
    X=zeros(nmax,1);
    X(1)=Xinit_vec(j);
    %iteration
    for n=1:nmax-1
        X(n+1)=f(X(n));
    end;
    XX(:,j)=X;
end;
plot(app.UIAxes,nvec,XX,'x-','linewidth',2)


        end

        % Value changing function: rSlider
        function rSliderValueChanging(app, event)
            changingValue = event.Value;
                 r=app.rSlider.Value;
            f=@(X) r*X.*(1-X);
            Xinit_vec=[0.3];
            nmax=30;
            [ST,I] = dbstack;
calling_filename=ST(2).file;

num_ICs=length(Xinit_vec);

%create vector of n values
nvec=(1:nmax)';

for j=1:num_ICs
    X=zeros(nmax,1);
    X(1)=Xinit_vec(j);
    %iteration
    for n=1:nmax-1
        X(n+1)=f(X(n));
    end;
    XX(:,j)=X;
end;
plot(app.UIAxes,nvec,XX,'x-','linewidth',2)
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'f(x)=rx(1-x)')
            xlabel(app.UIAxes, 'n')
            ylabel(app.UIAxes, 'Xn')
            app.rSliderLabel = uilabel(app.UIFigure);
            app.rSliderLabel.Text = 'r';
            app.rSlider = uislider(app.UIFigure);
            app.rSlider.Limits = [0 5];
            app.rSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3 3.2 3.4 3.6 3.8 4 4.2 4.4 4.6 4.8 5];
            app.rSlider.ValueChangedFcn = createCallbackFcn(app, @rSliderValueChanged, true);
            app.rSlider.ValueChangingFcn = createCallbackFcn(app, @rSliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end